classdef Fitting_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FittingappUIFigure          matlab.ui.Figure
        StatusTextArea              matlab.ui.control.TextArea
        StatusTextAreaLabel         matlab.ui.control.Label
        FileEditField               matlab.ui.control.EditField
        FileEditFieldLabel          matlab.ui.control.Label
        SelectfileButton            matlab.ui.control.Button
        PreviousplotsCheckBox       matlab.ui.control.CheckBox
        RefitButton                 matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        DataTab                     matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        ModelselectionButtonGroup   matlab.ui.container.ButtonGroup
        KineticButton               matlab.ui.control.RadioButton
        CyberneticButton            matlab.ui.control.RadioButton
        SubstratesEditField         matlab.ui.control.EditField
        SubstratesEditFieldLabel    matlab.ui.control.Label
        SpeciesEditField            matlab.ui.control.EditField
        SpeciesEditFieldLabel       matlab.ui.control.Label
        n_SubstratesEditField       matlab.ui.control.NumericEditField
        n_SubstratesEditFieldLabel  matlab.ui.control.Label
        n_SpeciesEditField          matlab.ui.control.NumericEditField
        n_SpeciesEditFieldLabel     matlab.ui.control.Label
        ComputingTab                matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        TrystoppingButton           matlab.ui.control.StateButton
        RepeatSlider                matlab.ui.control.Slider
        RepeatSliderLabel           matlab.ui.control.Label
        RandomizeCheckBox           matlab.ui.control.CheckBox
        TimeweightedCheckBox        matlab.ui.control.CheckBox
        UseParallelCheckBox         matlab.ui.control.CheckBox
        RandomnoseedEditField       matlab.ui.control.NumericEditField
        RandomnoseedEditFieldLabel  matlab.ui.control.Label
        FminconSlider               matlab.ui.control.Slider
        PatternsearchSlider         matlab.ui.control.Slider
        FminconSliderLabel          matlab.ui.control.Label
        PatternsearchSliderLabel    matlab.ui.control.Label
        FittingButton               matlab.ui.control.Button
        ExportPlotButton            matlab.ui.control.Button
        LoadDataButton              matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
    end

    
    properties (Access = private)
        t1
        ax
        ax_ind
        ax_num
        colors
        Par1
        ParallelOn
        ModelSel
        TimeWt
        selFol
        random
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.colors = distinguishable_colors(10);
            app.t1= tiledlayout(app.Panel,2,2);
            app.ParallelOn = false;
            app.ModelSel = 2;
            app.TimeWt = 0;
            app.selFol = pwd;
            app.random = 0;

            set(0,'DefaultFigureColor','w');
            set(0,'DefaultAxesFontSize',14,'DefaultTextFontSize',14);
            set(0,'DefaultAxesFontName','Arial','DefaultTextFontName','Arial');
            set(0,'DefaultLineLinewidth',2);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            file_name = strcat(app.FileEditField.Value,".xlsx");
            file_name = fullfile(app.selFol,file_name);

            [Ci_time,Ci_data,Sj_time,Sj_data,Species,Substrates,Enzymes,n_species,n_substrates,Tjji] = load_from_table(file_name);

            app.n_SpeciesEditField.Value = n_species;
            app.n_SubstratesEditField.Value = n_substrates;
            app.SpeciesEditField.Value = strjoin(Species,", ");
            app.SubstratesEditField.Value = strjoin(Substrates,", ");
            
            %%
            app.ax(1) = nexttile(app.t1,1); hold(app.ax(1),"on")
            for i = 1:n_species
                plot(app.ax(1),Ci_time,Ci_data(:,i),'o','Color', app.colors(i,:));
            end
            ylabel(app.ax(1),'Species Concentration');
            legend(app.ax(1),Species,'Location','Best')
            ylim(app.ax(1),[0 1.1*max(Ci_data,[],'all')]);
            xlim(app.ax(1),[0 max([Ci_time;Sj_time])])
            
            app.ax(2) = nexttile(app.t1,2); hold(app.ax(2),"on")
            for j = 1:n_substrates
                plot(app.ax(2),Sj_time,Sj_data(:,j),'o','Color', app.colors(n_species+j,:));
            end
            ylabel(app.ax(2),'Substrate Concentration');
            legend(app.ax(2),Substrates,'Location','Best');
            ylim(app.ax(2),[0 1.1*max(Sj_data,[],'all')]);
            xlim(app.ax(2),[0 max([Ci_time;Sj_time])])
            
            app.ax(3) = nexttile(app.t1,3); hold(app.ax(3),"on")
            ylabel(app.ax(3),'Intracellular Enzyme Concentration');
            legend(app.ax(3),Enzymes,'Location','Best'); 
%             ylim(app.ax(3),[0 1]);
            xlim(app.ax(3),[0 max([Ci_time;Sj_time])])

            app.ax(4) = nexttile(app.t1,4); hold(app.ax(4),"on")
            ylabel(app.ax(4),'Extracellular Enzyme Concentration');
            legend(app.ax(4),Enzymes,'Location','Best'); 
%             ylim(app.ax(4),[0 1]);
            xlim(app.ax(4),[0 max([Ci_time;Sj_time])])

            linkaxes(app.ax,'x');
            xlabel(app.t1,'time (hr)');
            
            app.LoadDataButton.Enable = "off";
            app.FittingButton.Enable = "on";
            app.ClearButton.Enable = "on";
            app.StatusTextArea.Value = "Data loaded";
            
            mfilename=strcat(app.FileEditField.Value,".mat");
            mfilename = fullfile(app.selFol,mfilename);
            if isfile(mfilename)
            else
                sol_name = {'Model';'SSE';'Time-weighted';'mu1';'K';'m';'Y';'k_c';'K_c';'f';'k_e';'alpha1';'beta1';'k_t';'e_initial'};
                m_file = matfile(mfilename, 'Writable', true);
                m_file.solutions=sol_name;
                clear m_file;
            end
            % %scroll(app.StatusTextArea,"bottom")
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            app.ExportPlotButton.Enable ="off";
            pause(0.01)
            file_name2 = strcat(app.selFol,app.FileEditField.Value,".pdf");
            exportgraphics(app.t1,file_name2,'ContentType','vector')
            app.ExportPlotButton.Enable ="on";
        end

        % Button pushed function: FittingButton
        function FittingButtonPushed(app, event)
%             try
                seed = app.RandomnoseedEditField.Value;
                rng(seed);
                app.LoadDataButton.Enable = "off";
                app.FittingButton.Enable = "off";
                app.RefitButton.Enable = "off";
                app.ExportPlotButton.Enable = "off";
                app.ClearButton.Enable = "off";
                
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fitting under progress ..."];
                pause(0.01)
                file_name = strcat(app.FileEditField.Value,".xlsx");
                file_name = fullfile(app.selFol,file_name);
                
                [Ci_time,Ci_data,Sj_time,Sj_data,Species,Substrates,Enzymes,n_species,n_substrates,Tjji] = load_from_table(file_name);
    
                [Par, Par_size, Par_sp_size, Par_sub_size, LB, UB] = Par_from_table(file_name);
                [mu1,K,m,Y,k_c,K_c,f,k_e,alpha1,beta1,k_t,e_initial,err] = Par_list(Par, Par_size, Par_sp_size, Par_sub_size, n_species, n_substrates);
                
                if err==true
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "Incorrect initial parameter dimensions"];
                    app.LoadDataButton.Enable = "on";
                    return
                end
                
                app.Par1 = Par;
    
    
                if app.TimeWt==0
                    Ci_max_data_time = repmat(max(Ci_data).^1,length(Ci_time),1);
                    Sj_max_data_time = repmat(max(Sj_data).^1,length(Sj_time),1);
                    timewt = "off";
                elseif app.TimeWt==1
                    Ci_max_data_time = repmat(max(Ci_data).^1,length(Ci_time),1).*(Ci_time.^(1/4));
                    Sj_max_data_time = repmat(max(Sj_data).^1,length(Sj_time),1).*(Sj_time.^(1/4));
                    timewt = "on";
                end
                
                ps_iter = round(app.PatternsearchSlider.Value);
                ls_iter = round(app.FminconSlider.Value);
                parallelOn = app.ParallelOn;
                modelSel = app.ModelSel;

                par1 = app.Par1;

                const = par1 == LB & par1 == UB;
                kkk = cumsum(Par_size);
                if n_substrates<=3
                    const(kkk(6)+1:kkk(8)) = true;
                end
                if n_substrates<=2
                    const(kkk(4)+1:kkk(8)) = true;
                end


                logpar1 = log10(par1(~const));
                loglb = log10(LB(~const));
                logub = log10(UB(~const));
                if app.random==1
                    logpar1 = normrnd(logpar1,0.01*(logub-logpar1)-0.01*(loglb-logpar1));
                end

    
                clc
                tic
                opts = optimoptions('patternsearch','Display','iter',...
                    'InitialMeshSize',1,'MeshContractionFactor',0.9,'MeshExpansionFactor',2,...
                    'MaxIterations',ps_iter,'MaxFunctionEvaluations',5000,'UseParallel',parallelOn);
                if app.TrystoppingButton.Value ==1
                    app.ClearButton.Enable = "on";
                    app.FittingButton.Enable = "on";
                    app.TrystoppingButton.Value =0;
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fitting haulted"];
                    return
                end
                
%                 par1_const=[par1, const];
%                 Par_sp_sub_size= [Par_sp_size,Par_sub_size]; 
%                 details = [n_species,n_substrates,modelSel,app.TimeWt]; 
                
                [logpar1, errormulti] = patternsearch(@(x) ...
                    sum((obj_cyb(x,par1,const,Par_size, Par_sp_size, Par_sub_size,Ci_time,Ci_data,Sj_time,Sj_data,Ci_max_data_time,Sj_max_data_time,n_species,n_substrates,Tjji,modelSel)).^2,'all'),...
                    logpar1,[],[],[],[],loglb,logub,[],opts);
                time_taken = toc;
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Patternsearch complete"];
                temp = strcat("SSE = ", num2str(errormulti), "; Time taken = ", num2str(time_taken));
                app.StatusTextArea.Value = [app.StatusTextArea.Value; temp];
                pause(0.01);
    
                %           
                % clc 
%                 tic
%                 opts2 = optimoptions('lsqnonlin','MaxIterations',ls_iter,'MaxFunctionEvaluations',5000,'Display','iter','UseParallel',parallelOn);
%                 if app.TrystoppingButton.Value ==1
%                     app.ClearButton.Enable = "on";
%                     app.FittingButton.Enable = "on";
%                     app.TrystoppingButton.Value =0;
%                     app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fitting haulted"];
%                     return
%                 end
%                 if n_substrates==1 && n_species ==1
%                     opts2.Algorithm = 'levenberg-marquardt';
%                 end
%                 [logpar1,errormulti] = lsqnonlin(@(x)obj_cyb...
%                     (x,par1,const,Par_size, Par_sp_size, Par_sub_size,Ci_time,Ci_data,Sj_time,Sj_data,Ci_max_data_time,Sj_max_data_time,n_species,n_substrates,Tjji,modelSel),logpar1,loglb,logub,[],[],[],[],[],opts2);
%                 time_taken = toc;
%                 app.StatusTextArea.Value = [app.StatusTextArea.Value; "Lsqnonlin complete"];
%                 temp = strcat("SSE = ", num2str(errormulti), "; Time taken = ", num2str(time_taken));
%                 app.StatusTextArea.Value = [app.StatusTextArea.Value; temp];
%                 pause(0.01);
                
%                 clc 
                tic
                opts2 = optimoptions('fmincon','MaxIterations',ls_iter,'MaxFunctionEvaluations',5000,'Display','iter','UseParallel',parallelOn);
                if app.TrystoppingButton.Value ==1
                    app.ClearButton.Enable = "on";
                    app.FittingButton.Enable = "on";
                    app.TrystoppingButton.Value =0;
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fitting haulted"];
                    return
                end
                [logpar1,errormulti] = fmincon(@(x)...
                    sum(obj_cyb(x,par1,const,Par_size, Par_sp_size, Par_sub_size,Ci_time,Ci_data,Sj_time,Sj_data,Ci_max_data_time,Sj_max_data_time,n_species,n_substrates,Tjji,modelSel).^2,'all')...
                    ,logpar1,[],[],[],[],loglb,logub,[],opts2);
                time_taken = toc;
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fmincon complete"];
                temp = strcat("SSE = ", num2str(errormulti), "; Time taken = ", num2str(time_taken));
                app.StatusTextArea.Value = [app.StatusTextArea.Value; temp];
                pause(0.01);

                par1(~const) = 10.^logpar1;
                
                app.Par1= par1;
                Par = app.Par1;
                [mu1,K,m,Y,k_c,K_c,f,k_e,alpha1,beta1,k_t,e_initial,err] = Par_list(Par, Par_size, Par_sp_size, Par_sub_size, n_species, n_substrates);
                
                % eij_initial = Par(end-n_species*n_substrates+1:end,1)';
                x_in = [Ci_data(1,:), Sj_data(1,:), e_initial, zeros(size(e_initial))];
                
                [t,y] = solve_cyb(Par,Par_size, Par_sp_size, Par_sub_size,[min([Ci_time; Sj_time]) max([Ci_time; Sj_time])],x_in,n_species,n_substrates,Tjji,modelSel);
                Ci = y(:,1:n_species);
                Sj = y(:,n_species+1:n_species+n_substrates);
                eij = y(:,n_species+n_substrates+1:n_species+n_substrates+n_species*n_substrates);
                Eij = y(:,n_species+n_substrates+n_species*n_substrates+1:n_species+n_substrates+2*n_species*n_substrates);
                
    %             save(['Fit_PF_' num2str(1) '.mat']);
                app.ax_ind = 1;
                for i = 1:n_species
                    app.ax_num(app.ax_ind)= plot(app.ax(1),t,Ci(:,i),'Color', app.colors(i,:));
                    app.ax_ind = app.ax_ind+1;
                end
                legend(app.ax(1),Species,'Location','Best')
    
                for j = 1:n_substrates
                    app.ax_num(app.ax_ind)= plot(app.ax(2),t,Sj(:,j),'Color', app.colors(n_species+j,:));
                    app.ax_ind = app.ax_ind+1;
                end
                legend(app.ax(2),Substrates,'Location','Best');
                
                style = {'-','--',':','-.'};
                for j = 1:n_substrates
                    for i= 1:n_species
                        app.ax_num(app.ax_ind)= plot(app.ax(3),t, eij(:,(j-1)*n_species+i),'Color',app.colors(n_species+j,:),'LineStyle',style{i});
                        app.ax_ind = app.ax_ind+1;
                    end
                end
                legend(app.ax(3),Enzymes,'Location','Best');

                for j = 1:n_substrates
                    for i= 1:n_species
                        app.ax_num(app.ax_ind)= plot(app.ax(4),t, Eij(:,(j-1)*n_species+i),'Color',app.colors(n_species+j,:),'LineStyle',style{i});
                        app.ax_ind = app.ax_ind+1;
                    end
                end
                legend(app.ax(4),Enzymes,'Location','Best');
                
                model = app.ModelselectionButtonGroup.SelectedObject.Text;
                
                solutions = {model,errormulti,timewt,mu1,K,m,Y,k_c,K_c,f,k_e,alpha1,beta1,k_t,reshape(e_initial,n_species,n_substrates)}';
                mfilename=strcat(app.FileEditField.Value,".mat");
                mfilename = fullfile(app.selFol,mfilename);
                m_file = matfile(mfilename, 'Writable', true);
                m_file.solutions(:,end+1)=solutions;
                clear m_file;
                
    
                app.RefitButton.Enable = "on";
                app.ExportPlotButton.Enable = "on";
                app.ClearButton.Enable = "on";
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fitting done"];
%             catch fiterr
%                 app.StatusTextArea.Value = [app.StatusTextArea.Value; "Unable to run fitting algorithm. Check for errors."];
%                 app.ClearButton.Enable = "on";
%                 app.FittingButton.Enable = "on";
%                 fiterr
%             end
            % %scroll(app.StatusTextArea,"bottom")

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            try
                cla(app.ax(1));cla(app.ax(2));cla(app.ax(3));
            catch
                
            end
            app.ExportPlotButton.Enable = "off";
            app.ClearButton.Enable = "off";
            app.FittingButton.Enable = "off";
            app.RefitButton.Enable = "off";
            app.LoadDataButton.Enable = "on";
            app.PreviousplotsCheckBox.Enable = "off";
            app.StatusTextArea.Value = '';
        end

        % Button pushed function: RefitButton
        function RefitButtonPushed(app, event)
%             try
                button{1} = app.LoadDataButton.Enable;
                button{2} = app.FittingButton.Enable;
                button{3} = app.RefitButton.Enable;
                button{4} = app.ExportPlotButton.Enable;
                button{5} = app.ClearButton.Enable;
                
                app.LoadDataButton.Enable = "off";
                app.FittingButton.Enable = "off";
                app.RefitButton.Enable = "off";
                app.ExportPlotButton.Enable = "off";
                app.ClearButton.Enable = "off";
    
                app.PreviousplotsCheckBox.Enable = "on";
                
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Refitting under progress ..."];
                pause(0.01)
                file_name = strcat(app.FileEditField.Value,".xlsx");
                file_name = fullfile(app.selFol,file_name);
                [Ci_time,Ci_data,Sj_time,Sj_data,Species,Substrates,Enzymes,n_species,n_substrates,Tjji] = load_from_table(file_name);
    
                [~, Par_size, Par_sp_size, Par_sub_size, LB, UB] = Par_from_table(file_name);
                
    
                if app.TimeWt==0
                    Ci_max_data_time = repmat(max(Ci_data).^1,length(Ci_time),1);
                    Sj_max_data_time = repmat(max(Sj_data).^1,length(Sj_time),1);
                    timewt = "off";
                elseif app.TimeWt==1
                    Ci_max_data_time = repmat(max(Ci_data).^1,length(Ci_time),1).*(Ci_time.^(1/4));
                    Sj_max_data_time = repmat(max(Sj_data).^1,length(Sj_time),1).*(Sj_time.^(1/4));
                    timewt = "on";
                end
    
                ps_iter = round(app.PatternsearchSlider.Value);
                ls_iter = round(app.FminconSlider.Value);
                parallelOn = app.ParallelOn;
                modelSel = app.ModelSel;

                par1 = app.Par1;

                const = par1 == LB & par1 == UB;
                logpar1 = log10(par1(~const));
                loglb = log10(LB(~const));
                logub = log10(UB(~const));
                if app.random==1
                    logpar1 = normrnd(logpar1,0.01*(logub-logpar1)-0.01*(loglb-logpar1));
                end
    
                clc
                for i = 1:round(app.RepeatSlider.Value)
                    tic
                    opts = optimoptions('patternsearch','Display','iter',...
                        'InitialMeshSize',0.1,'MeshContractionFactor',0.1,'MeshExpansionFactor',2,...
                        'MaxIterations',ps_iter,'MaxFunctionEvaluations',5000,'UseParallel',parallelOn);
                    if app.TrystoppingButton.Value ==1
                        app.ClearButton.Enable = "on";
                        app.RefitButton.Enable = "on";
                        app.ExportPlotButton.Enable = "on";
                        app.TrystoppingButton.Value =0;
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fitting haulted"];
                        return
                    end
                    pause(0.01)
                    [logpar1, errormulti] = patternsearch(@(x) ...
                        sum((obj_cyb(x,par1,const,Par_size, Par_sp_size, Par_sub_size,Ci_time,Ci_data,Sj_time,Sj_data,...
                        Ci_max_data_time,Sj_max_data_time,n_species,n_substrates,Tjji,modelSel)).^2,'all'),...
                        logpar1,[],[],[],[],loglb,logub,[],opts);
                    time_taken = toc;
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "Patternsearch complete"];
                    temp = strcat("SSE = ", num2str(errormulti), "; Time taken = ", num2str(time_taken));
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; temp];
                    pause(0.01);
        
                    %           
                    % clc 
%                     tic
%                     opts2 = optimoptions('lsqnonlin','MaxIterations',ls_iter,'MaxFunctionEvaluations',5000,'Display','iter','UseParallel',parallelOn);
%                     if app.TrystoppingButton.Value ==1
%                         app.ClearButton.Enable = "on";
%                         app.RefitButton.Enable = "on";
%                         app.ExportPlotButton.Enable = "on";
%                         app.TrystoppingButton.Value =0;
%                         app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fitting haulted"];
%                         return
%                     end
%     
%                     if n_substrates==1 && n_species ==1
%                         opts2.Algorithm = 'levenberg-marquardt';
%                     end
%                     [logpar1,errormulti] = lsqnonlin(@(x)...
%                         obj_cyb(x,par1,const,Par_size, Par_sp_size, Par_sub_size,Ci_time,Ci_data,Sj_time,Sj_data,...
%                         Ci_max_data_time,Sj_max_data_time,n_species,n_substrates,Tjji,modelSel)...
%                         ,logpar1,loglb,logub,[],[],[],[],[],opts2);
%                     time_taken = toc;
%                     app.StatusTextArea.Value = [app.StatusTextArea.Value; "Lsqnonlin complete"];
%                     temp = strcat("SSE = ", num2str(errormulti), "; Time taken = ", num2str(time_taken));
%                     app.StatusTextArea.Value = [app.StatusTextArea.Value; temp];
%                     pause(0.01);
                    
                    tic
                    opts2 = optimoptions('fmincon','MaxIterations',ls_iter,'MaxFunctionEvaluations',5000,'Display','iter','UseParallel',parallelOn);
                    if app.TrystoppingButton.Value ==1
                        app.ClearButton.Enable = "on";
                        app.FittingButton.Enable = "on";
                        app.TrystoppingButton.Value =0;
                        app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fitting haulted"];
                        return
                    end
                    [logpar1,errormulti] = fmincon(@(x)...
                        sum(obj_cyb(x,par1,const,Par_size, Par_sp_size, Par_sub_size,Ci_time,Ci_data,Sj_time,Sj_data,Ci_max_data_time,Sj_max_data_time,n_species,n_substrates,Tjji,modelSel).^2,'all')...
                        ,logpar1,[],[],[],[],loglb,logub,[],opts2);
                    time_taken = toc;
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "Fmincon complete"];
                    temp = strcat("SSE = ", num2str(errormulti), "; Time taken = ", num2str(time_taken));
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; temp];
                    pause(0.01);
                end

                par1(~const) = 10.^logpar1;
                
                app.Par1= par1;
                
                Par = app.Par1;
                [mu1,K,m,Y,k_c,K_c,f,k_e,alpha1,beta1,k_t,e_initial,err] = Par_list(Par, Par_size, Par_sp_size, Par_sub_size, n_species, n_substrates);
                
                x_in = [Ci_data(1,:), Sj_data(1,:), e_initial, zeros(size(e_initial))];
                
                [t,y] = solve_cyb(Par,Par_size, Par_sp_size, Par_sub_size,[min([Ci_time; Sj_time]) max([Ci_time; Sj_time])],x_in,n_species,n_substrates,Tjji,modelSel);
                Ci = y(:,1:n_species);
                Sj = y(:,n_species+1:n_species+n_substrates);
                eij = y(:,n_species+n_substrates+1:n_species+n_substrates+n_species*n_substrates);
                Eij = y(:,n_species+n_substrates+n_species*n_substrates+1:n_species+n_substrates+2*n_species*n_substrates);
                
                
                for jj = 1:app.ax_ind-1
                    axis1 = app.ax_num(jj);
                    set(axis1,'visible','off')
                end
    
                for i = 1:n_species
                    app.ax_num(app.ax_ind)= plot(app.ax(1),t,Ci(:,i),'Color', app.colors(i,:));
                    app.ax_ind = app.ax_ind+1;
                end
                legend(app.ax(1),Species,'Location','Best')
    
                for j = 1:n_substrates
                    app.ax_num(app.ax_ind)= plot(app.ax(2),t,Sj(:,j),'Color', app.colors(n_species+j,:));
                    app.ax_ind = app.ax_ind+1;
                end
                legend(app.ax(2),Substrates,'Location','Best');
                
                style = {'-','--',':','-.'};
                for j = 1:n_substrates
                    for i= 1:n_species
                        app.ax_num(app.ax_ind)= plot(app.ax(3),t, eij(:,(j-1)*n_species+i),'Color',app.colors(n_species+j,:),'LineStyle',style{i});
                        app.ax_ind = app.ax_ind+1;
                    end
                end
                legend(app.ax(3),app.ax_num((app.ax_ind-n_species*n_substrates):app.ax_ind-1),Enzymes,'Location','Best');

                for j = 1:n_substrates
                    for i= 1:n_species
                        app.ax_num(app.ax_ind)= plot(app.ax(4),t, Eij(:,(j-1)*n_species+i),'Color',app.colors(n_species+j,:),'LineStyle',style{i});
                        app.ax_ind = app.ax_ind+1;
                    end
                end
                legend(app.ax(4),app.ax_num((app.ax_ind-n_species*n_substrates):app.ax_ind-1),Enzymes,'Location','Best');
    
                app.StatusTextArea.Value = [app.StatusTextArea.Value; "Refitting done"];
                
                
                model = app.ModelselectionButtonGroup.SelectedObject.Text;
                solutions = {model,errormulti,timewt,mu1,K,m,Y,k_c,K_c,f,k_e,alpha1,beta1,k_t,reshape(e_initial,n_species,n_substrates)}';
                mfilename=strcat(app.FileEditField.Value,".mat");
                mfilename = fullfile(app.selFol,mfilename);
                m_file = matfile(mfilename, 'Writable', true);
                m_file.solutions(:,end+1)=solutions;
                clear m_file;
                
                app.LoadDataButton.Enable = button{1};
                app.FittingButton.Enable = button{2};
                app.RefitButton.Enable = button{3};
                app.ExportPlotButton.Enable = button{4};
                app.ClearButton.Enable = button{5};
                % %scroll(app.StatusTextArea,"bottom")
%             catch fiterr
%                 app.StatusTextArea.Value = [app.StatusTextArea.Value; "Unable to run fitting algorithm. Check for errors."];
%                 app.ClearButton.Enable = "on";
%                 app.RefitButton.Enable = "on";
%                 app.ExportPlotButton.Enable = "on";
%                 fiterr
%             end
            % %scroll(app.StatusTextArea,"bottom")

        end

        % Value changed function: UseParallelCheckBox
        function UseParallelCheckBoxValueChanged(app, event)
            value = app.UseParallelCheckBox.Value;
            if value ==1
                p = gcp('nocreate');
                if isempty(p)
                    button{1} = app.LoadDataButton.Enable;
                    button{2} = app.FittingButton.Enable;
                    button{3} = app.RefitButton.Enable;
                    button{4} = app.ExportPlotButton.Enable;
                    button{5} = app.ClearButton.Enable;
                    
                    app.LoadDataButton.Enable = "off";
                    app.FittingButton.Enable = "off";
                    app.RefitButton.Enable = "off";
                    app.ExportPlotButton.Enable = "off";
                    app.ClearButton.Enable = "off";
                    
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "Initializing parallel pool..."];
                    pause(0.01)
                    
                    parpool
                    app.StatusTextArea.Value = [app.StatusTextArea.Value; "Done"];
                    
                    app.LoadDataButton.Enable = button{1};
                    app.FittingButton.Enable = button{2};
                    app.RefitButton.Enable = button{3};
                    app.ExportPlotButton.Enable = button{4};
                    app.ClearButton.Enable = button{5};
                end
                app.ParallelOn = true;
            elseif value ==0
                app.ParallelOn = false;
            end
            % %scroll(app.StatusTextArea,"bottom")
        end

        % Callback function
        function ModelselectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelselectionButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Kinetic')==1
                app.StatusTextArea.Value = [app.StatusTextArea.Value; [selectedButton.Text ' model is selected']];
                app.ModelSel = 1;
                app.CyberneticButtonGroup.Visible = "off";
            elseif strcmp(selectedButton.Text,'Cybernetic')==1
                app.StatusTextArea.Value = [app.StatusTextArea.Value; [selectedButton.Text ' model is selected']];
                app.ModelSel = 2;
                app.CyberneticButtonGroup.Visible = "on";
            end
            % %scroll(app.StatusTextArea,"bottom")
        end

        % Value changed function: PreviousplotsCheckBox
        function PreviousplotsCheckBoxValueChanged(app, event)
            value = app.PreviousplotsCheckBox.Value;
            file_name = strcat(app.FileEditField.Value,".xlsx");
            [Ci_time,Ci_data,Sj_time,Sj_data,Species,Substrates,Enzymes,n_species,n_substrates,Tjji] = load_from_table(file_name);
            if value ==1
                for jj = 1:(app.ax_ind-1-n_species-n_substrates-2*n_species*n_substrates)
                    axis1 = app.ax_num(jj);
                    set(axis1,'visible','on')
                    color = get(axis1,'Color') ;
                    set(axis1, 'Color', [color(1,1:3) 0.5]);
                end
            elseif value ==0
                for jj = 1:(app.ax_ind-1-n_species-n_substrates-2*n_species*n_substrates)
                    axis1 = app.ax_num(jj);
                    set(axis1,'visible','off')
                end
            end
            % %scroll(app.StatusTextArea,"bottom")
        end

        % Value changed function: TimeweightedCheckBox
        function TimeweightedCheckBoxValueChanged(app, event)
            value = app.TimeweightedCheckBox.Value;
            app.TimeWt = value;
            if value ==1
                app.StatusTextArea.Value = [app.StatusTextArea.Value; 'Time-weighted model selected'];
            elseif value ==0
                app.StatusTextArea.Value = [app.StatusTextArea.Value; 'No time-weighted model selected'];
            end
            % %scroll(app.StatusTextArea,"bottom")
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            try
                [file,path] = uigetfile('*.xlsx');
                [~,name,~] = fileparts(file);
                app.FileEditField.Value = name;
                app.selFol = path;
                figure(app.FittingappUIFigure)
            catch
                figure(app.FittingappUIFigure)
            end
            % %scroll(app.StatusTextArea,"bottom")
        end

        % Value changed function: RandomizeCheckBox
        function RandomizeCheckBoxValueChanged(app, event)
            value = app.RandomizeCheckBox.Value;
            app.random = value;
            if value ==1
                app.StatusTextArea.Value = [app.StatusTextArea.Value; 'Initial guess will be randomized'];
            elseif value ==0
                app.StatusTextArea.Value = [app.StatusTextArea.Value; 'Uses previous fit solution'];
            end
            % %scroll(app.StatusTextArea,"bottom")
        end

        % Value changed function: RepeatSlider
        function RepeatSliderValueChanged(app, event)
            % determine which discrete option the current value is closest to.
            [~, minIdx] = min(abs(event.Source.Value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
        end

        % Value changed function: TrystoppingButton
        function TrystoppingButtonValueChanged(app, event)
            value = app.TrystoppingButton.Value;
            if value ==1
                app.ClearButton.Enable = "on";
                app.RefitButton.Enable = "on";
                app.ExportPlotButton.Enable = "on";
                app.TrystoppingButton.Value =0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FittingappUIFigure = uifigure('Visible', 'off');
            app.FittingappUIFigure.Name = 'Fitting app';
            app.Panel = uipanel(app.FittingappUIFigure);
            app.LoadDataButton = uibutton(app.FittingappUIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ExportPlotButton = uibutton(app.FittingappUIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Enable = 'off';
            app.ExportPlotButton.Text = 'Export Plot';
            app.FittingButton = uibutton(app.FittingappUIFigure, 'push');
            app.FittingButton.ButtonPushedFcn = createCallbackFcn(app, @FittingButtonPushed, true);
            app.FittingButton.Enable = 'off';
            app.FittingButton.Text = 'Fitting';
            app.TabGroup = uitabgroup(app.FittingappUIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.GridLayout = uigridlayout(app.DataTab);
            app.n_SpeciesEditFieldLabel = uilabel(app.GridLayout);
            app.n_SpeciesEditFieldLabel.Text = 'n_Species';
            app.n_SpeciesEditField = uieditfield(app.GridLayout, 'numeric');
            app.n_SpeciesEditField.Limits = [0 10];
            app.n_SpeciesEditField.RoundFractionalValues = 'on';
            app.n_SpeciesEditField.Editable = 'off';
            app.n_SubstratesEditFieldLabel = uilabel(app.GridLayout);
            app.n_SubstratesEditFieldLabel.Text = 'n_Substrates';
            app.n_SubstratesEditField = uieditfield(app.GridLayout, 'numeric');
            app.n_SubstratesEditField.Limits = [0 10];
            app.n_SubstratesEditField.RoundFractionalValues = 'on';
            app.n_SubstratesEditField.Editable = 'off';
            app.SpeciesEditFieldLabel = uilabel(app.GridLayout);
            app.SpeciesEditFieldLabel.Text = 'Species';
            app.SpeciesEditField = uieditfield(app.GridLayout, 'text');
            app.SpeciesEditField.Editable = 'off';
            app.SubstratesEditFieldLabel = uilabel(app.GridLayout);
            app.SubstratesEditFieldLabel.Text = 'Substrates';
            app.SubstratesEditField = uieditfield(app.GridLayout, 'text');
            app.SubstratesEditField.Editable = 'off';
            app.ModelselectionButtonGroup = uibuttongroup(app.GridLayout);
            app.ModelselectionButtonGroup.Title = 'Model selection';
            app.CyberneticButton = uiradiobutton(app.ModelselectionButtonGroup);
            app.CyberneticButton.Text = 'Cybernetic';
            app.CyberneticButton.Value = true;
            app.KineticButton = uiradiobutton(app.ModelselectionButtonGroup);
            app.KineticButton.Text = 'Kinetic';
            app.ComputingTab = uitab(app.TabGroup);
            app.ComputingTab.Title = 'Computing';
            app.GridLayout2 = uigridlayout(app.ComputingTab);
            app.PatternsearchSliderLabel = uilabel(app.GridLayout2);
            app.PatternsearchSliderLabel.Text = 'Patternsearch';
            app.FminconSliderLabel = uilabel(app.GridLayout2);
            app.FminconSliderLabel.Text = 'Fmincon';
            app.PatternsearchSlider = uislider(app.GridLayout2);
            app.PatternsearchSlider.MinorTicks = [];
            app.PatternsearchSlider.Value = 20;
            app.FminconSlider = uislider(app.GridLayout2);
            app.FminconSlider.MinorTicks = 0;
            app.FminconSlider.Value = 10;
            app.RandomnoseedEditFieldLabel = uilabel(app.GridLayout2);
            app.RandomnoseedEditFieldLabel.Text = 'Random no. seed';
            app.RandomnoseedEditField = uieditfield(app.GridLayout2, 'numeric');
            app.UseParallelCheckBox = uicheckbox(app.GridLayout2);
            app.UseParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseParallelCheckBoxValueChanged, true);
            app.UseParallelCheckBox.Text = 'Use Parallel';
            app.TimeweightedCheckBox = uicheckbox(app.GridLayout2);
            app.TimeweightedCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeweightedCheckBoxValueChanged, true);
            app.TimeweightedCheckBox.Text = 'Time-weighted';
            app.RandomizeCheckBox = uicheckbox(app.GridLayout2);
            app.RandomizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RandomizeCheckBoxValueChanged, true);
            app.RandomizeCheckBox.Text = 'Randomize';
            app.RepeatSliderLabel = uilabel(app.GridLayout2);
            app.RepeatSliderLabel.Text = 'Repeat';
            app.RepeatSlider = uislider(app.GridLayout2);
            app.RepeatSlider.Limits = [1 10];
            app.RepeatSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.RepeatSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.RepeatSlider.ValueChangedFcn = createCallbackFcn(app, @RepeatSliderValueChanged, true);
            app.RepeatSlider.MinorTicks = [];
            app.RepeatSlider.Value = 1;
            app.TrystoppingButton = uibutton(app.GridLayout2, 'state');
            app.TrystoppingButton.ValueChangedFcn = createCallbackFcn(app, @TrystoppingButtonValueChanged, true);
            app.TrystoppingButton.Text = 'Try stopping';
            app.ClearButton = uibutton(app.FittingappUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.RefitButton = uibutton(app.FittingappUIFigure, 'push');
            app.RefitButton.ButtonPushedFcn = createCallbackFcn(app, @RefitButtonPushed, true);
            app.RefitButton.Enable = 'off';
            app.RefitButton.Text = 'Refit';
            app.PreviousplotsCheckBox = uicheckbox(app.FittingappUIFigure);
            app.PreviousplotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PreviousplotsCheckBoxValueChanged, true);
            app.PreviousplotsCheckBox.Enable = 'off';
            app.PreviousplotsCheckBox.Text = 'Previous plots';
            app.SelectfileButton = uibutton(app.FittingappUIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.FileEditFieldLabel = uilabel(app.FittingappUIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.FittingappUIFigure, 'text');
            app.FileEditField.Value = 'Sample_data';
            app.StatusTextAreaLabel = uilabel(app.FittingappUIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.FittingappUIFigure);
            app.StatusTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.FittingappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fitting_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FittingappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FittingappUIFigure)
        end
    end
end